classdef SeaSurfaceTemperatureExplorerAppDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SeaSurfaceTemperatureExplorerUIFigure  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        TrendPanel          matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        StatisticalSignificanceCheckbox  matlab.ui.control.CheckBox
        TrendsMap           matlab.ui.control.UIAxes
        TemperaturePanel    matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        YearSlider          matlab.ui.control.Slider
        TemperatureMap      matlab.ui.control.UIAxes
        BrowseFileButton    matlab.ui.control.Button
        FileEditField       matlab.ui.control.EditField
        FileEditFieldLabel  matlab.ui.control.Label
        TimeHistoryPlot     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Filename       % Description
        Filepath        % Filename with path

        Data            % Structure containing data loaded from file

        SelectedLatitude    %   Description
        SelectedLongitude % Description
        TemperatureMapMarker % Description
        TrendMapMarker % Description
    end
    
    methods (Access = private)
        
        function updateTemperatureMap(app)
            data = app.Data;
            Year = app.YearSlider.Value;
            ax = app.TemperatureMap;

            yearind = find(year(app.Data.t) == Year);
%             imagescn(data.lon,data.lat,data.sst(:,:,yearind),"Parent",ax,"HitTest","off");      % CDT imagesc with transparent NaN
            im = imagesc(data.lon,data.lat,data.sst(:,:,yearind),"Parent",ax,"HitTest","off");               
            im.AlphaData = isfinite(im.CData);  % Make NaNs transparent

            
            xlabel(ax,"Longitude")
            ylabel(ax,"Latitude")
            axis(ax,"equal")
            axis(ax,"xy")
            axis(ax,"tight")

            cb = colorbar(ax);
            ylabel(cb,'\circC')
%             [~,name] = fileparts(app.Filename); % Name without extension
%             title(ax,name + " sea surface temperature in "  + Year)
            title(ax,"Temperature in "  + Year)
            cmap = cmocean('thermal');
            colormap(ax,cmap)

            % geographic borders
            hold(ax,"on")
            borders_modified('country','parent',ax)
            hold(ax,"off")

            axis(ax,[min(data.lon) max(data.lon) min(data.lat) max(data.lat)])

            % Mark Latitude and Longitude for time history plot
            app.TemperatureMapMarker = createLatitudeLongitudeMarker(app,ax);
        end

        function updateTrendMap(app)
            data = app.Data;
            ssttrend = trend(data.sst);        % Annual trend
            sig = mann_kendall(data.sst);     % Statistical significance

            firstyear = year(data.t(1));
            lastyear = year(data.t(end));
            ax = app.TrendsMap;
%             imagescn(data.lon,data.lat,ssttrend,"Parent",ax,"HitTest","off");
            im = imagesc(data.lon,data.lat,ssttrend,"Parent",ax,"HitTest","off");               
            im.AlphaData = isfinite(im.CData);  % Make NaNs transparent

            
            xlabel(ax,"Longitude")
            ylabel(ax,"Latitude")
            axis(ax,"equal")
            axis(ax,"xy")
            
            % Use my modified version of cmocean that allows me to specify
            % the axes
            cmocean_modified('balance','pivot',0,'axes',ax)

            cb = colorbar(ax);
            ylabel(cb,"\circC / year")

%             [~,name] = fileparts(app.Filename); % Name without extension

%             titlestring = name + " sea surface temperature annual trend " + firstyear + " to " + lastyear;
            titlestring = "Annual trend " + firstyear + " to " + lastyear;
            title(ax,titlestring)

            % geographic borders
            hold(ax,"on")
            borders_modified('country','parent',ax)
            hold(ax,"off")

            axis(ax,[min(data.lon) max(data.lon) min(data.lat) max(data.lat)])
            
            showstipple = app.StatisticalSignificanceCheckbox.Value;
            if showstipple
                hold(ax,"on")
                stipple(data.lon,data.lat,sig,"Parent",ax,"HitTest","off");
                hold(ax,"off")
            end

            % Mark Latitude and Longitude for time history plot
             app.TrendMapMarker = createLatitudeLongitudeMarker(app,ax);


        end
        
        function h = createLatitudeLongitudeMarker(app,ax)
            hold(ax,"on")
            h = plot(ax,app.SelectedLongitude,app.SelectedLatitude,"p","MarkerSize",30,"MarkerFaceColor","y","MarkerEdgeColor",[1 .65 0],"Tag","LatLonMarker");
            hold(ax,"off")
        end
        

        function loadDataAndUpdateApp(app)
            % Load data
            app.Data = load(fullfile(app.Filepath,app.Filename));

            % Update Year slider
            [firstyear,lastyear] = bounds(year(app.Data.t)); % Year limits
            app.YearSlider.Limits = [firstyear, lastyear];
            app.YearSlider.Value = round(mean([firstyear, lastyear]));
            app.YearSlider.MajorTicks = [firstyear, lastyear];
            app.YearSlider.MinorTicks = firstyear:10:lastyear;

            % Set default location for detail (time history) plot
            app.SelectedLatitude = mean(app.Data.lat);
            app.SelectedLongitude = mean(app.Data.lon);

            % Do all the things
            updateTemperatureMap(app)
            updateTrendMap(app)
            updateTimeHistoryPlot(app)
        end
        
        function updateTimeHistoryPlot(app)
            % Update the time history plot
            lat = app.Data.lat;
            lon = app.Data.lon;
            t = app.Data.t;
            sst = app.Data.sst;
            Latitude = app.SelectedLatitude;
            Longitude = app.SelectedLongitude;


            % Find nearest actual latitude/longitude values
            latind = near1(lat,Latitude);
            lonind = near1(lon,Longitude);
            sst1 = squeeze(sst(latind,lonind,:));

            % Update latitude to reflect actual value
            Latitude = lat(latind);
            Longitude = lon(lonind);
            app.SelectedLatitude = Latitude;
            app.SelectedLongitude = Longitude;

            % Create plot
            plot(app.TimeHistoryPlot,t,sst1)
            xlabel(app.TimeHistoryPlot,"Time")
            ylabel(app.TimeHistoryPlot,"Temperature")

            % Create latitude and longitude labels using N-S-E-W
            LatLabel = string(abs(Latitude));
            if Latitude > 0
                LatLabel = LatLabel + "\circ N";
            else
                LatLabel = LatLabel + "\circ S";
            end

            LonLabel = string(abs(Longitude));
            if Longitude > 0
                LonLabel = LonLabel + "\circ E";
            else
                LonLabel = LonLabel + "\circ W";
            end

            titlestring = "Temperature at (" + LatLabel + ", " + LonLabel + ")";

            if ~any(isnan(sst1))  % Add linear trend line and trend summary, as long as there's valid data here
                hold(app.TimeHistoryPlot,"on")
                pp = polyplot(t,sst1,'Parent',app.TimeHistoryPlot);
                
                % Create informative title
                annualIncrease = diff(pp.YData); % Increase of polyfit over time period, grabbed from plot handle
                numYears = years(range(t));
                if annualIncrease>=0
                    direction = " increased ";
                else
                    direction = " decreased ";
                end

                titlestring = titlestring + direction + annualIncrease + "\circ over " + numYears + " years";
                hold(app.TimeHistoryPlot,"off")
            end

            title(app.TimeHistoryPlot,titlestring)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load some default data if have the ScriptToApp project open,
            % otherwise prompt for a data file.
            
            cp = matlab.project.rootProject;
            if ~isempty(cp) && cp.Name == "ScriptToApp"
                app.Filename = "Pacific.mat";
                app.Filepath = fullfile(cp.RootFolder,"Data/");
            else % No project open, prompt for a data file
                [app.Filename, app.Filepath] = uigetfile("*.mat","Select a data file");
            end
            app.FileEditField.Value = app.Filename;
            loadDataAndUpdateApp(app)
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            % Pick a file
            [app.Filename, app.Filepath] = uigetfile("*.mat","Select a data file");
            app.FileEditField.Value = app.Filename;  

            loadDataAndUpdateApp(app)
        end

        % Value changed function: YearSlider
        function YearSliderValueChanged(app, event)
            app.YearSlider.Value = round(app.YearSlider.Value);
            updateTemperatureMap(app)          
        end

        % Value changed function: StatisticalSignificanceCheckbox
        function StatisticalSignificanceCheckboxValueChanged(app, event)
%             value = app.StatisticalSignificanceCheckbox.Value;
            
            % Try a shortcut and see if it's fast enough. Just update the
            % entire plot
            updateTrendMap(app)
        end

        % Button down function: TemperatureMap, TrendsMap
        function MapButtonDown(app, event)
            % Update selected location
            app.SelectedLongitude = event.IntersectionPoint(1);
            app.SelectedLatitude = event.IntersectionPoint(2);

            % Update location of marker, on both plots
            app.TemperatureMapMarker.XData = app.SelectedLongitude;
            app.TemperatureMapMarker.YData = app.SelectedLatitude;
            app.TrendMapMarker.XData = app.SelectedLongitude;
            app.TrendMapMarker.YData = app.SelectedLatitude;

            % Update time history plot
            updateTimeHistoryPlot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SeaSurfaceTemperatureExplorerUIFigure = uifigure('Visible', 'off');
            app.SeaSurfaceTemperatureExplorerUIFigure.Name = 'Sea Surface Temperature Explorer';
            app.GridLayout = uigridlayout(app.SeaSurfaceTemperatureExplorerUIFigure);
            app.TimeHistoryPlot = uiaxes(app.GridLayout);
            title(app.TimeHistoryPlot, 'Title')
            xlabel(app.TimeHistoryPlot, 'X')
            ylabel(app.TimeHistoryPlot, 'Y')
            zlabel(app.TimeHistoryPlot, 'Z')
            app.FileEditFieldLabel = uilabel(app.GridLayout);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.GridLayout, 'text');
            app.FileEditField.Editable = 'off';
            app.BrowseFileButton = uibutton(app.GridLayout, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Tooltip = {'Select a data file'};
            app.BrowseFileButton.Text = '...';
            app.TemperaturePanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.TemperaturePanel);
            app.TemperatureMap = uiaxes(app.GridLayout2);
            title(app.TemperatureMap, 'Title')
            xlabel(app.TemperatureMap, 'X')
            ylabel(app.TemperatureMap, 'Y')
            zlabel(app.TemperatureMap, 'Z')
            app.TemperatureMap.ButtonDownFcn = createCallbackFcn(app, @MapButtonDown, true);
            app.YearSlider = uislider(app.GridLayout2);
            app.YearSlider.Limits = [1980 2010];
            app.YearSlider.MajorTicks = [1980 2010];
            app.YearSlider.ValueChangedFcn = createCallbackFcn(app, @YearSliderValueChanged, true);
            app.YearSlider.MinorTicks = [1980 1981 1982 1983 1984 1985 1986 1987 1988 1989 1990 1991 1992 1993 1994 1995 1996 1997 1998 1999 2000 2001 2002 2003 2004 2005 2006 2007 2008 2009 2010];
            app.YearSlider.Value = 1980;
            app.TrendPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.TrendPanel);
            app.TrendsMap = uiaxes(app.GridLayout3);
            title(app.TrendsMap, 'Title')
            xlabel(app.TrendsMap, 'X')
            ylabel(app.TrendsMap, 'Y')
            zlabel(app.TrendsMap, 'Z')
            app.TrendsMap.ButtonDownFcn = createCallbackFcn(app, @MapButtonDown, true);
            app.StatisticalSignificanceCheckbox = uicheckbox(app.GridLayout3);
            app.StatisticalSignificanceCheckbox.ValueChangedFcn = createCallbackFcn(app, @StatisticalSignificanceCheckboxValueChanged, true);
            app.StatisticalSignificanceCheckbox.Text = 'Show statistical significance';
            % Show the figure after all components are created
            app.SeaSurfaceTemperatureExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeaSurfaceTemperatureExplorerAppDesigner

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SeaSurfaceTemperatureExplorerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SeaSurfaceTemperatureExplorerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SeaSurfaceTemperatureExplorerUIFigure)
        end
    end
end